classdef OptionsAdditionalMarkers < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AdditionalMarkersUIFigure  matlab.ui.Figure
        Panel                      matlab.ui.container.Panel
        OKButton                   matlab.ui.control.Button
        OffsetmEditFieldLabel      matlab.ui.control.Label
        OffsetmEditField           matlab.ui.control.NumericEditField
        WidthmEditFieldLabel       matlab.ui.control.Label
        WidthmEditField            matlab.ui.control.NumericEditField
    end

properties (Access = private)
        CallingApp
        AnalysisParameters
end
    methods (Access = private)
        
        function NewAnalysisParameters = ActuateParameters(app,OldAnalysisParameters)
            OldAnalysisParameters.Prediction.Offset = app.OffsetmEditField.Value;
            OldAnalysisParameters.Prediction.Width = app.WidthmEditField.Value;
            % Actuate
            NewAnalysisParameters = OldAnalysisParameters;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, OldAnalysisParameters)
            app.CallingApp = mainapp;
            % Actualisation des paramètres
            app.AnalysisParameters = OldAnalysisParameters;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.AnalysisParameters = ActuateParameters(app,app.AnalysisParameters);
            SaveAnalysisParameters(app.CallingApp,app.AnalysisParameters);
            app.AdditionalMarkersUIFigureCloseRequest; 
        end

        % Close request function: AdditionalMarkersUIFigure
        function AdditionalMarkersUIFigureCloseRequest(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AdditionalMarkersUIFigure = uifigure('Visible', 'off');
            app.AdditionalMarkersUIFigure.Name = 'UI Figure';
            app.AdditionalMarkersUIFigure.CloseRequestFcn = createCallbackFcn(app, @AdditionalMarkersUIFigureCloseRequest, true);
            app.Panel = uipanel(app.AdditionalMarkersUIFigure);
            app.OKButton = uibutton(app.Panel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.OffsetmEditFieldLabel = uilabel(app.AdditionalMarkersUIFigure);
            app.OffsetmEditFieldLabel.Text = 'Offset (m)';
            app.OffsetmEditField = uieditfield(app.AdditionalMarkersUIFigure, 'numeric');
            app.WidthmEditFieldLabel = uilabel(app.AdditionalMarkersUIFigure);
            app.WidthmEditFieldLabel.Text = 'Width (m)';
            app.WidthmEditField = uieditfield(app.AdditionalMarkersUIFigure, 'numeric');
            % Show the figure after all components are created
            app.AdditionalMarkersUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OptionsAdditionalMarkers(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AdditionalMarkersUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AdditionalMarkersUIFigure)
        end
    end
end